classdef STLApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        TabGroup                      matlab.ui.container.TabGroup
        GeneratingSTLTab              matlab.ui.container.Tab
        Create3DModelButton           matlab.ui.control.Button
        SelectSaveLocationLabel       matlab.ui.control.Label
        DirectoryButton               matlab.ui.control.Button
        ImportedFilestatusLabel       matlab.ui.control.Label
        Import                        matlab.ui.control.Button
        smoothingFactorLabel          matlab.ui.control.Label
        SmoothingSlider               matlab.ui.control.Slider
        SmoothingSliderLabel          matlab.ui.control.Label
        OutputStlnameEditField        matlab.ui.control.EditField
        OutputStlnameEditFieldLabel   matlab.ui.control.Label
        OptionalFeaturesTab           matlab.ui.container.Tab
        AddBaseCheckBox               matlab.ui.control.CheckBox
        PrintedNameEditField          matlab.ui.control.EditField
        PrintedNameEditFieldLabel     matlab.ui.control.Label
        EndingYvalueEditField         matlab.ui.control.NumericEditField
        EndingYvalueEditFieldLabel    matlab.ui.control.Label
        StartingYvalueEditField       matlab.ui.control.NumericEditField
        StartingYvalueEditFieldLabel  matlab.ui.control.Label
        EndingXvalueEditField         matlab.ui.control.NumericEditField
        EndingXvalueEditFieldLabel    matlab.ui.control.Label
        StartingXvalueEditField       matlab.ui.control.NumericEditField
        StartingXvalueEditFieldLabel  matlab.ui.control.Label
        DimensionLabels               matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        DDataintoRealWorldLabel       matlab.ui.control.Label
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        Menu_2                        matlab.ui.container.Menu
        Menu2_2                       matlab.ui.container.Menu
    end


    properties (Access = private)
        selected_file %The raw data file
        STL_name char % STL file name
        smoothing % the guassian filter's factor for smoothing( more factor =  more smoothed)
        Raw_data %the raw data read by readtable command(input of the data to STL func)
        filepath %path of the uploaded file
        finaldir %if the user choose specific folder to save the STL file there
        plot_name char %the plot name to be printed with the 2D data
        start_x %the starting value of the x axis
        end_x % the end value of the x axis
        start_y %the starting value of the y axis
        end_y %the ending value of the y axis
        base
    end

    methods (Access = private)

        %Data to STL
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [F_solid, V_solid , tri] = DataToStl( Raw_data ,smoothing_factor )
            %This functions turn a raw data into Stl file, ready for print

            %inputs:
            %Raw_data : one column data, in Csv ot txt format
            %smoothing_factor

            %outputs:
            %3D plot of the solid object
            %Stl file saved as GC×GC.stl

            % Read the file
            data = Raw_data;

            %turn the data table into array
            data = table2array(data) ;

            %Extracting relative X and Y from Z matrix
            [x, y] = meshgrid(1:size(data, 2), 1:size(data, 1));

            %Lowering the number of data by decimation
            decimationFactor = 2;
            y_decimated = y(1:decimationFactor:end, :);
            x_decimated = x(1:decimationFactor:end, :);
            z_decimated = data(1:decimationFactor:end, :);

            % Apply Gaussian smoothing
            z_smoothed = imgaussfilt(z_decimated, smoothing_factor);

            %Scaling
            %This process have 2 parts, first defining an scale factor and then scaling
            %scale factors for X and Y are defined in away that the final X and Y size would be 150 and 100
            %the Z height is the mean of the X and Y
            %X
            x_ScaleFactor = 150 / max (x_decimated(:));
            x_scaled = x_decimated * x_ScaleFactor;
            %Y
            y_ScaleFactor = 100 / max (y_decimated(:));
            y_scaled = y_decimated * y_ScaleFactor;
            %Z
            z_ScaleFactor = ((100 + 150)/2) / max(z_smoothed(:)) ; %defining a scale factor to multiply all the Z and height to it (this transform the highest point of the graph to be mean of the X and Y size)%This make the printing easily
            z_scaled = z_smoothed * z_ScaleFactor ; %scale all the Z data

            % Flatten for triangulation
            vertices = [x_scaled(:), y_scaled(:), z_scaled(:)]; %make vertices out of the data points(make a new matrix out of 3 matrix(each row is a 3D point
            valid_indices = all(isfinite(vertices), 2); %remove unidentified values
            vertices = vertices(valid_indices, :); %keeping the rows of the matrix with valid values

            % generates a Delaunay triangulation using the X and Y coordinates from the vertices matrix
            faces = delaunay(vertices(:, 1), vertices(:, 2));

            % Create a solid volume from positive data
            [F_solid, V_solid] = surf2solid(faces, vertices, 'elevation', -5); %2 is theheight of solid bottom(can be changed based on the data)

            % Save STL file for positive peaks
            tri = triangulation(F_solid, V_solid); %This command creates a triangulation object in MATLAB, which provides geometric and topological information about the mesh.

        end
        %&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&&
        function [combined_V , combined_F ,TR] = DataWithName( Raw_data ,smoothing_factor , Data_name )

            % Reading the Data, smooth it, scale it and generate the faces and vertices

            data = Raw_data;

            %turn the data table into array
            data = table2array(data) ;

            %Extracting relative X and Y from Z matrix
            [x, y] = meshgrid(1:size(data, 2), 1:size(data, 1));

            %Lowering the number of data by decimation
            decimationFactor = 2;
            y_decimated = y(1:decimationFactor:end, :);
            x_decimated = x(1:decimationFactor:end, :);
            z_decimated = data(1:decimationFactor:end, :);

            % Apply Gaussian smoothing
            z_smoothed = imgaussfilt(z_decimated, smoothing_factor);

            %Scaling
            %This process have 2 parts, first defining an scale factor and then scaling
            %scale factors for X and Y are defined in away that the final X and Y size would be 150 and 100
            %the Z height is the mean of the X and Y
            %X
            x_ScaleFactor = 150 / max (x_decimated(:));
            x_scaled = x_decimated * x_ScaleFactor;
            %Y
            y_ScaleFactor = 100 / max (y_decimated(:));
            y_scaled = y_decimated * y_ScaleFactor;
            %Z
            z_ScaleFactor = ((100 + 150)/2) / max(z_smoothed(:)) ; %defining a scale factor to multiply all the Z and height to it (this transform the highest point of the graph to be mean of the X and Y size)%This make the printing easily
            z_scaled = z_smoothed * z_ScaleFactor ; %scale all the Z data

            % Flatten for triangulation
            vertices = [x_scaled(:), y_scaled(:), z_scaled(:)]; %make vertices out of the data points(make a new matrix out of 3 matrix(each row is a 3D point
            valid_indices = all(isfinite(vertices), 2); %remove unidentified values
            vertices = vertices(valid_indices, :); %keeping the rows of the matrix with valid values

            % generates a Delaunay triangulation using the X and Y coordinates from the vertices matrix
            faces = delaunay(vertices(:, 1), vertices(:, 2));

            % Create a solid volume from positive data
            [F_solid, V_solid] = surf2solid(faces, vertices, 'elevation', -6); %2 is theheight of solid bottom(can be changed based on the data)

            % Create Solid Cube

            %if the user put data for the file name od dimention values then goes to
            %the

            %creating a solid cube which the x dimension is 130 and y simension is 180
            %the height of the cube is 5
            vertices = [0 0 0;
                130 0 0;
                130 180 0;
                0 180 0;
                0 0 5;
                130 0 5;
                130 180 5;
                0 180 5];

            %2D triangulation using x and y points
            faces = delaunay(vertices(:, 1), vertices(:, 2));

            %converting the surface into solid using surf2solid command
            %put the elevation 10 and generating solid faces and solid vertices
            [Solid_faces, Solid_vertices] = surf2solid(faces, vertices, 'elevation', 10);

            % Create 3D Text Letter by Letter

            %get the inout letters
            letters = char(Data_name);
            fontSize = 15; %font size
            depth = 5; %the amount of extruding
            spacing = 12; %the spacing between each character
            startY = 0; %the starting point

            %for making the letters as standing ones we need a matrix to multiply and
            %turn the upright
            R = [cos(pi/2) 0 sin(pi/2); 0 1 0; -sin(pi/2) 0 cos(pi/2)];

            %make an empty cells for the words
            %creates a cell which has 1 row and length * 2 column
            %*2 = 2 vertices, one for back and one for front(like faces)
            all_text_vertices = cell(1, length(letters)*2);
            all_text_faces = cell(1, length(letters)*2);

            %how many vertices have already been added
            vertex_offset = 0;

            %Track where you are in your list of geometry pieces
            face_idx = 1;


            for i = 1:length(letters)

                if letters(i) == ' ' %check if the character is blank
                    startY = startY + spacing;%change the starting point

                    %if the characters is space, then when its reached to this line it
                    %will start over a new iteration in for loop
                    continue
                end

                %turn the text into numbers and matrix(its not binary yet)
                %generate a RGB one
                %generate a 50 × 50 gray scale which the insert text will return it
                %into RGB
                %starting point is (0 , 0)
                %adjusting the font and font size
                %turn the background opocity to zero
                %the text colors are white
                img = insertText(zeros(50,50), [0 0], letters(i), ...
                    'FontSize', fontSize,'Font', 'Arial Black', ...   % Use a naturally bold font
                    'BoxOpacity', 0, ...
                    'TextColor', 'white');

                %make the RGB into gray + make the image binary
                BW = imbinarize(rgb2gray(img));
                %rotating 270 degree and flip it
                %this is essential for showing the letters
                BW = imrotate(BW, 270);
                BW = fliplr(BW);
                %extract the boundary coordinates of white shapes
                B= bwboundaries(BW, 'noholes');
                %placing a letters in 130 x, change the starting point for the next
                %letter , and Z in 0
                pos = [130; 10 + startY; 0];

                %looping through all detected boundaries in the binary image
                for k = 1:length(B)
                    % reduces the number of points
                    boundary = B{k}; % downsample for speed

                    %extracting x and y
                    x = boundary(:,2);
                    y = boundary(:,1);
                    z0 = zeros(size(x)); %create zero z matrix
                    z1 = depth * ones(size(x)); %create the matrix of z with depth
                    %the bottom face
                    %make a matrix of x,y,and z which each are in a row
                    %multiply to a matrix which make it stands upright
                    coords0 = R * [x'; y'; z0'];
                    %the top face
                    coords1 = R * [x'; y'; z1'];
                    minZ = min(coords0(3,:));%find min z
                    coords0(3,:) = coords0(3,:) - minZ; %shifting z to be zero
                    coords1(3,:) = coords1(3,:) - minZ; %shifting z to be zero
                    coords0 = coords0 + pos; % turn the x to be 130, shift the y
                    coords1 = coords1 + pos; % turn the x to be 130, shift the y
                    %gets the number of columns, i.e., the number of points around the boundary
                    %This number is important for building the side walls of the extruded mesh
                    n = size(coords0, 2);
                    v0 = coords0'; %This represents the bottom face of the extruded letter.
                    v1 = coords1'; %Same for the top face
                    all_text_vertices{face_idx} = [v0; v1]; %storing both layers of vertices in your cell array
                    faces_temp = []; %Initializes an empty list of triangle faces.
                    %connecting side faces
                    for m = 2:n-1
                        faces_temp(end+1,:) = [vertex_offset+1, vertex_offset+m, vertex_offset+m+1];
                        faces_temp(end+1,:) = [vertex_offset+n+1, vertex_offset+n+m, vertex_offset+n+m+1];
                    end
                    %extruding side walls
                    for m = 1:n-1
                        a = vertex_offset + m;
                        b = vertex_offset + m + 1;
                        c = vertex_offset + n + m + 1;
                        d = vertex_offset + n + m;
                        faces_temp(end+1,:) = [a b c];
                        faces_temp(end+1,:) = [a c d];
                    end
                    %storing the triangulated faces and matrices
                    all_text_faces{face_idx} = faces_temp;
                    vertex_offset = vertex_offset + 2 * n;
                    face_idx = face_idx + 1;
                end
                %make the y ready for the next letter
                startY = startY + spacing;
            end
            %merging all the individual letters' geometry
            all_text_vertices = vertcat(all_text_vertices{:});
            all_text_faces = vertcat(all_text_faces{:});

            % Add Custom Object on Top of Cube
            % Assume user provides: f_solid, v_solid
            % Position custom object centered on top of cube (e.g., 75, 100, 20)
            custom_translation = [110, 20, 12];
            rotation_xy = [cosd(90) -sind(90) 0; sind(90) cosd(90) 0; 0 0 1];
            v_solid_rotated = (rotation_xy * V_solid')';
            %moving 3D object
            v_solid_translated = bsxfun(@plus, v_solid_rotated, custom_translation);
            f_solid_shifted = F_solid + size(Solid_vertices, 1) + size( ...
                all_text_vertices, 1);

            % Combine All and Export
            text_faces_shifted = all_text_faces + size(Solid_vertices, 1);
            combined_V = [Solid_vertices; all_text_vertices; v_solid_translated];
            combined_F = [Solid_faces; text_faces_shifted ; f_solid_shifted];
            TR = triangulation(combined_F, combined_V);
        end
        %**********************************************************************

        function [combined_V, combined_F , TR] = DataWithDetails( Raw_data ,smoothing_factor , Data_name , dimension_num)
            %This functions turn a raw data into Stl file, ready for print
            %the final STL file will have a name of the file, and the numbers related
            %to the dimensions

            %inputs:
            %Raw_data : one column data, in Csv ot txt format
            %smoothing_factor
            %Data_name
            %dimension_num : will contains 4 number as an array which each is related
            %to the starting and ending point of the x and y data(while the z data is
            %about the detctor's intensity,
            % example: {120 , 240 , 350 , 780}
            %120 : y starting point
            %240 : y ending point
            %350 : x starting point
            %780 : x ending point

            %outputs:
            %3D plot of the solid object
            %Stl file saved with the number and text on it
            % Reading the Data, smooth it, scale it and generate the faces and vertices

            data =Raw_data;

            %turn the data table into array
            data = table2array(data) ;

            %Extracting relative X and Y from Z matrix
            [x, y] = meshgrid(1:size(data, 2), 1:size(data, 1));

            %Lowering the number of data by decimation
            decimationFactor = 2;
            y_decimated = y(1:decimationFactor:end, :);
            x_decimated = x(1:decimationFactor:end, :);
            z_decimated = data(1:decimationFactor:end, :);

            % Apply Gaussian smoothing
            z_smoothed = imgaussfilt(z_decimated, smoothing_factor);

            %Scaling
            %This process have 2 parts, first defining an scale factor and then scaling
            %scale factors for X and Y are defined in away that the final X and Y size would be 150 and 100
            %the Z height is the mean of the X and Y
            %X
            x_ScaleFactor = 150 / max (x_decimated(:));
            x_scaled = x_decimated * x_ScaleFactor;
            %Y
            y_ScaleFactor = 100 / max (y_decimated(:));
            y_scaled = y_decimated * y_ScaleFactor;
            %Z
            z_ScaleFactor = ((100 + 150)/2) / max(z_smoothed(:)) ; %defining a scale factor to multiply all the Z and height to it (this transform the highest point of the graph to be mean of the X and Y size)%This make the printing easily
            z_scaled = z_smoothed * z_ScaleFactor ; %scale all the Z data

            % Flatten for triangulation
            vertices = [x_scaled(:), y_scaled(:), z_scaled(:)]; %make vertices out of the data points(make a new matrix out of 3 matrix(each row is a 3D point
            valid_indices = all(isfinite(vertices), 2); %remove unidentified values
            vertices = vertices(valid_indices, :); %keeping the rows of the matrix with valid values

            % generates a Delaunay triangulation using the X and Y coordinates from the vertices matrix
            faces = delaunay(vertices(:, 1), vertices(:, 2));

            % Create a solid volume from positive data
            [F_solid, V_solid] = surf2solid(faces, vertices, 'elevation', -6); %2 is theheight of solid bottom(can be changed based on the data)

            % Create Solid Cube

            %creating a solid cube which the x dimension is 130 and y simension is 180
            %the height of the cube is 5
            vertices = [0 0 0;
                130 0 0;
                130 180 0;
                0 180 0;
                0 0 5;
                130 0 5;
                130 180 5;
                0 180 5];

            %2D triangulation using x and y points
            faces = delaunay(vertices(:, 1), vertices(:, 2));

            %converting the surface into solid using surf2solid command
            %put the elevation 10 and generating solid faces and solid vertices
            [Solid_faces, Solid_vertices] = surf2solid(faces, vertices, 'elevation', 10);

            % Create 3D Text Letter by Letter

            %get the input letters
            letters = char(Data_name);
            fontSize = 15; %font size
            depth = 5; %the amount of extruding
            spacing = 12; %the spacing between each character
            startY = 0; %the starting point

            %for making the letters as standing ones we need a matrix to multiply and
            %turn the upright
            R = [cos(pi/2) 0 sin(pi/2); 0 1 0; -sin(pi/2) 0 cos(pi/2)];

            %make an empty cells for the words
            %creates a cell which has 1 row and length * 2 column
            %*2 = 2 vertices, one for back and one for front(like faces)
            all_text_vertices = cell(1, length(letters)*2);
            all_text_faces = cell(1, length(letters)*2);

            %how many vertices have already been added
            vertex_offset = 0;

            %Track where you are in your list of geometry pieces
            face_idx = 1;


            for i = 1:length(letters)

                if letters(i) == ' ' %check if the character is blank
                    startY = startY + spacing;%change the starting point

                    %if the characters is space, then when its reached to this line it
                    %will start over a new iteration in for loop
                    continue
                end

                %turn the text into numbers and matrix(its not binary yet)
                %generate a RGB one
                %generate a 50 × 50 gray scale which the insert text will return it
                %into RGB
                %starting point is (0 , 0)
                %adjusting the font and font size
                %turn the background opocity to zero
                %the text colors are white
                img = insertText(zeros(50,50), [0 0], letters(i), ...
                    'FontSize', fontSize,'Font', 'Arial Black', ...   % Use a naturally bold font
                    'BoxOpacity', 0, ...
                    'TextColor', 'white');

                %make the RGB into gray + make the image binary
                BW = imbinarize(rgb2gray(img));
                %rotating 270 degree and flip it
                %this is essential for showing the letters
                BW = imrotate(BW, 270);
                BW = fliplr(BW);
                %extract the boundary coordinates of white shapes
                B= bwboundaries(BW, 'noholes');
                %placing a letters in 130 x, change the starting point for the next
                %letter , and Z in 0
                pos = [130; 10 + startY; 0];

                %looping through all detected boundaries in the binary image
                for k = 1:length(B)
                    % reduces the number of points
                    boundary = B{k}; % downsample for speed

                    %extracting x and y
                    x = boundary(:,2);
                    y = boundary(:,1);
                    z0 = zeros(size(x)); %create zero z matrix
                    z1 = depth * ones(size(x)); %create the matrix of z with depth
                    %the bottom face
                    %make a matrix of x,y,and z which each are in a row
                    %multiply to a matrix which make it stands upright
                    coords0 = R * [x'; y'; z0'];
                    %the top face
                    coords1 = R * [x'; y'; z1'];
                    minZ = min(coords0(3,:));%find min z
                    coords0(3,:) = coords0(3,:) - minZ; %shifting z to be zero
                    coords1(3,:) = coords1(3,:) - minZ; %shifting z to be zero
                    coords0 = coords0 + pos; % turn the x to be 130, shift the y
                    coords1 = coords1 + pos; % turn the x to be 130, shift the y
                    %gets the number of columns, i.e., the number of points around the boundary
                    %This number is important for building the side walls of the extruded mesh
                    n = size(coords0, 2);
                    v0 = coords0'; %This represents the bottom face of the extruded letter.
                    v1 = coords1'; %Same for the top face
                    all_text_vertices{face_idx} = [v0; v1]; %storing both layers of vertices in your cell array
                    faces_temp = []; %Initializes an empty list of triangle faces.
                    %connecting side faces
                    for m = 2:n-1
                        faces_temp(end+1,:) = [vertex_offset+1, vertex_offset+m, vertex_offset+m+1];
                        faces_temp(end+1,:) = [vertex_offset+n+1, vertex_offset+n+m, vertex_offset+n+m+1];
                    end
                    %extruding side walls
                    for m = 1:n-1
                        a = vertex_offset + m;
                        b = vertex_offset + m + 1;
                        c = vertex_offset + n + m + 1;
                        d = vertex_offset + n + m;
                        faces_temp(end+1,:) = [a b c];
                        faces_temp(end+1,:) = [a c d];
                    end
                    %storing the triangulated faces and matrices
                    all_text_faces{face_idx} = faces_temp;
                    vertex_offset = vertex_offset + 2 * n;
                    face_idx = face_idx + 1;
                end
                %make the y ready for the next letter
                startY = startY + spacing;
            end
            %merging all the individual letters' geometry
            all_text_vertices = vertcat(all_text_vertices{:});
            all_text_faces = vertcat(all_text_faces{:});

            % Add Multiple Numbers
            %arrayfun : will do the function for each member of the input
            %num2str : convert the numbers into characters
            %'UniformOutput', false : we want the whole numbers to be in one cell not
            %each store in a seperate cell so this is true when they all store in
            %seperatedly cells
            number_labels = arrayfun(@num2str, dimension_num, 'UniformOutput', false);
            length(dimension_num(1))
            number_positions = [
                102, 13, 10; %do not need adjusting
                102, 130, 10;
                100,27, 10;
                0, 27, 10 %do not need adjusting
                ];

            %adjust the position depending on the figure
            if length(number_labels{2}) == 3
                number_positions(2 , :) = [102 , 140 , 10];
            elseif length(number_labels{2}) == (1 || 2)
                number_positions(2 , :) = [102 , 150 , 10];
            end
            if length(number_labels{3}) == 2
                number_positions(3 , :) = [90 , 27 , 10];
            elseif length(number_labels{3}) == 1
                number_positions(3 , :) = [90 , 27 , 10];
            elseif length(number_labels{3}) == 3
                number_positions(3 , :) = [80 , 27 , 10];
            end

            %Rotates the object 270° around the Z-axis in XY plane
            number_rotations = {eye(3), eye(3), ...
                [cosd(270) -sind(270) 0; sind(270) cosd(270) 0; 0 0 1], ...
                [cosd(270) -sind(270) 0; sind(270) cosd(270) 0; 0 0 1]};

            num_vertices = [];
            num_faces = [];
            v_offset = 0;
            fontSize = 15;
            depth = 5;

            for n = 1:length(number_labels)
                img = insertText(zeros(50,100), [0 0], number_labels{n}, ...
                    'FontSize', fontSize,'Font', 'Arial Black', ...   % Use a naturally bold font
                    'BoxOpacity', 0, ...
                    'TextColor', 'white');
                BW = imbinarize(rgb2gray(img));
                BW = imrotate(BW, 270);
                BW = fliplr(BW);
                B = bwboundaries(BW, 'noholes');

                for k = 1:length(B)
                    boundary = B{k}; % downsample
                    x = boundary(:,2);
                    y = boundary(:,1);
                    z0 = zeros(size(x));
                    z1 = depth * ones(size(x));
                    coords0 = number_rotations{n} * [x'; y'; z0'];
                    coords1 = number_rotations{n} * [x'; y'; z1'];
                    minZ = min(coords0(3,:));
                    coords0(3,:) = coords0(3,:) - minZ;
                    coords1(3,:) = coords1(3,:) - minZ;
                    coords0 = coords0 + number_positions(n, :)';
                    coords1 = coords1 + number_positions(n, :)';
                    numPts = size(coords0, 2);
                    v0 = coords0';
                    v1 = coords1';
                    num_vertices = [num_vertices; v0; v1];
                    for i = 2:numPts-1
                        num_faces(end+1,:) = [v_offset+1, v_offset+i, v_offset+i+1];
                        num_faces(end+1,:) = [v_offset+numPts+1, v_offset+numPts+i, v_offset+numPts+i+1];
                    end
                    for i = 1:numPts-1
                        v1i = v_offset + i;
                        v2i = v_offset + i + 1;
                        v3i = v_offset + numPts + i + 1;
                        v4i = v_offset + numPts + i;
                        num_faces(end+1,:) = [v1i v2i v3i];
                        num_faces(end+1,:) = [v1i v3i v4i];
                    end
                    v_offset = v_offset + 2 * numPts;
                end
            end

            % Add Custom Object on Top of Cube
            % Assume user provides: f_solid, v_solid
            % Position custom object centered on top of cube (e.g., 75, 100, 20)
            custom_translation = [110, 20, 12];
            rotation_xy = [cosd(90) -sind(90) 0; sind(90) cosd(90) 0; 0 0 1];
            v_solid_rotated = (rotation_xy * V_solid')';
            %moving 3D object
            v_solid_translated = bsxfun(@plus, v_solid_rotated, custom_translation);
            f_solid_shifted = F_solid + size(Solid_vertices, 1) + size( ...
                all_text_vertices, 1) + size(num_vertices, 1);

            % Combine All and Export
            text_faces_shifted = all_text_faces + size(Solid_vertices, 1);
            num_faces_shifted = num_faces + size(Solid_vertices, 1) + size(all_text_vertices, 1);
            combined_V = [Solid_vertices; all_text_vertices; num_vertices; v_solid_translated];
            combined_F = [Solid_faces; text_faces_shifted; num_faces_shifted; f_solid_shifted];
            TR = triangulation(combined_faces, combined_vertices);
        end % the main function
        %$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$
        function[combined_V, combined_F , TR] = DataWithNumbers( Raw_data ,smoothing_factor , dimension_num)

            data = Raw_data;

            %turn the data table into array
            data = table2array(data) ;

            %Extracting relative X and Y from Z matrix
            [x, y] = meshgrid(1:size(data, 2), 1:size(data, 1));

            %Lowering the number of data by decimation
            decimationFactor = 2;
            y_decimated = y(1:decimationFactor:end, :);
            x_decimated = x(1:decimationFactor:end, :);
            z_decimated = data(1:decimationFactor:end, :);

            % Apply Gaussian smoothing
            z_smoothed = imgaussfilt(z_decimated, smoothing_factor);

            %Scaling
            %This process have 2 parts, first defining an scale factor and then scaling
            %scale factors for X and Y are defined in away that the final X and Y size would be 150 and 100
            %the Z height is the mean of the X and Y
            %X
            x_ScaleFactor = 150 / max (x_decimated(:));
            x_scaled = x_decimated * x_ScaleFactor;
            %Y
            y_ScaleFactor = 100 / max (y_decimated(:));
            y_scaled = y_decimated * y_ScaleFactor;
            %Z
            z_ScaleFactor = ((100 + 150)/2) / max(z_smoothed(:)) ; %defining a scale factor to multiply all the Z and height to it (this transform the highest point of the graph to be mean of the X and Y size)%This make the printing easily
            z_scaled = z_smoothed * z_ScaleFactor ; %scale all the Z data

            % Flatten for triangulation
            vertices = [x_scaled(:), y_scaled(:), z_scaled(:)]; %make vertices out of the data points(make a new matrix out of 3 matrix(each row is a 3D point
            valid_indices = all(isfinite(vertices), 2); %remove unidentified values
            vertices = vertices(valid_indices, :); %keeping the rows of the matrix with valid values

            % generates a Delaunay triangulation using the X and Y coordinates from the vertices matrix
            faces = delaunay(vertices(:, 1), vertices(:, 2));

            % Create a solid volume from positive data
            [F_solid, V_solid] = surf2solid(faces, vertices, 'elevation', -6); %2 is theheight of solid bottom(can be changed based on the data)

            % Create Solid Cube

            %if the user put data for the file name od dimention values then goes to
            %the

            %creating a solid cube which the x dimension is 130 and y simension is 180
            %the height of the cube is 5
            vertices = [0 0 0;
                130 0 0;
                130 180 0;
                0 180 0;
                0 0 5;
                130 0 5;
                130 180 5;
                0 180 5];

            %2D triangulation using x and y points
            faces = delaunay(vertices(:, 1), vertices(:, 2));

            %converting the surface into solid using surf2solid command
            %put the elevation 10 and generating solid faces and solid vertices
            [Solid_faces, Solid_vertices] = surf2solid(faces, vertices, 'elevation', 10);

            % Add Multiple Numbers
            %arrayfun : will do the function for each member of the input
            %num2str : convert the numbers into characters
            %'UniformOutput', false : we want the whole numbers to be in one cell not
            %each store in a seperate cell so this is true when they all store in
            %seperatedly cells
            number_labels = arrayfun(@num2str, dimension_num, 'UniformOutput', false);
            length(dimension_num(1))
            number_positions = [
                102, 13, 10; %do not need adjusting
                102, 130, 10;
                100,27, 10;
                0, 27, 10 %do not need adjusting
                ];

            %adjust the position depending on the figure
            if length(number_labels{2}) == 3
                number_positions(2 , :) = [102 , 140 , 10];
            elseif length(number_labels{2}) == (1 || 2)
                number_positions(2 , :) = [102 , 150 , 10];
            end
            if length(number_labels{3}) == 2
                number_positions(3 , :) = [90 , 27 , 10];
            elseif length(number_labels{3}) == 1
                number_positions(3 , :) = [90 , 27 , 10];
            elseif length(number_labels{3}) == 3
                number_positions(3 , :) = [80 , 27 , 10];
            end

            %Rotates the object 270° around the Z-axis in XY plane
            number_rotations = {eye(3), eye(3), ...
                [cosd(270) -sind(270) 0; sind(270) cosd(270) 0; 0 0 1], ...
                [cosd(270) -sind(270) 0; sind(270) cosd(270) 0; 0 0 1]};

            num_vertices = [];
            num_faces = [];
            v_offset = 0;
            fontSize = 15;
            depth = 5;

            for n = 1:length(number_labels)
                img = insertText(zeros(50,100), [0 0], number_labels{n}, ...
                    'FontSize', fontSize,'Font', 'Arial Black', ...   % Use a naturally bold font
                    'BoxOpacity', 0, ...
                    'TextColor', 'white');
                BW = imbinarize(rgb2gray(img));
                BW = imrotate(BW, 270);
                BW = fliplr(BW);
                B = bwboundaries(BW, 'noholes');

                for k = 1:length(B)
                    boundary = B{k}; % downsample
                    x = boundary(:,2);
                    y = boundary(:,1);
                    z0 = zeros(size(x));
                    z1 = depth * ones(size(x));
                    coords0 = number_rotations{n} * [x'; y'; z0'];
                    coords1 = number_rotations{n} * [x'; y'; z1'];
                    minZ = min(coords0(3,:));
                    coords0(3,:) = coords0(3,:) - minZ;
                    coords1(3,:) = coords1(3,:) - minZ;
                    coords0 = coords0 + number_positions(n, :)';
                    coords1 = coords1 + number_positions(n, :)';
                    numPts = size(coords0, 2);
                    v0 = coords0';
                    v1 = coords1';
                    num_vertices = [num_vertices; v0; v1];
                    for i = 2:numPts-1
                        num_faces(end+1,:) = [v_offset+1, v_offset+i, v_offset+i+1];
                        num_faces(end+1,:) = [v_offset+numPts+1, v_offset+numPts+i, v_offset+numPts+i+1];
                    end
                    for i = 1:numPts-1
                        v1i = v_offset + i;
                        v2i = v_offset + i + 1;
                        v3i = v_offset + numPts + i + 1;
                        v4i = v_offset + numPts + i;
                        num_faces(end+1,:) = [v1i v2i v3i];
                        num_faces(end+1,:) = [v1i v3i v4i];
                    end
                    v_offset = v_offset + 2 * numPts;
                end
            end

            %Add Custom Object on Top of Cube
            % Assume user provides: f_solid, v_solid
            % Position custom object centered on top of cube (e.g., 75, 100, 20)
            custom_translation = [110, 20, 12];
            rotation_xy = [cosd(90) -sind(90) 0; sind(90) cosd(90) 0; 0 0 1];
            v_solid_rotated = (rotation_xy * V_solid')';
            %moving 3D object
            v_solid_translated = bsxfun(@plus, v_solid_rotated, custom_translation);
            f_solid_shifted = F_solid + size(Solid_vertices, 1) + size(num_vertices, 1);

            % Combine All and Export
            num_faces_shifted = num_faces + size(Solid_vertices, 1);
            combined_V = [Solid_vertices; num_vertices; v_solid_translated];
            combined_F = [Solid_faces; num_faces_shifted; f_solid_shifted];
            TR = triangulation(combined_F, combined_V);
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            function [combined_V , combined_F ,TR] = DataWithBase(Raw_data ,smoothing_factor)

                % Reading the Data, smooth it, scale it and generate the faces and vertices

                data = Raw_data

                %turn the data table into array
                data = table2array(data) ;

                %Extracting relative X and Y from Z matrix
                [x, y] = meshgrid(1:size(data, 2), 1:size(data, 1));

                %Lowering the number of data by decimation
                decimationFactor = 2;
                y_decimated = y(1:decimationFactor:end, :);
                x_decimated = x(1:decimationFactor:end, :);
                z_decimated = data(1:decimationFactor:end, :);

                % Apply Gaussian smoothing
                z_smoothed = imgaussfilt(z_decimated, smoothing_factor);

                %Scaling
                %This process have 2 parts, first defining an scale factor and then scaling
                %scale factors for X and Y are defined in away that the final X and Y size would be 150 and 100
                %the Z height is the mean of the X and Y
                %X
                x_ScaleFactor = 150 / max (x_decimated(:));
                x_scaled = x_decimated * x_ScaleFactor;
                %Y
                y_ScaleFactor = 100 / max (y_decimated(:));
                y_scaled = y_decimated * y_ScaleFactor;
                %Z
                z_ScaleFactor = ((100 + 150)/2) / max(z_smoothed(:)) ; %defining a scale factor to multiply all the Z and height to it (this transform the highest point of the graph to be mean of the X and Y size)%This make the printing easily
                z_scaled = z_smoothed * z_ScaleFactor ; %scale all the Z data

                % Flatten for triangulation
                vertices = [x_scaled(:), y_scaled(:), z_scaled(:)]; %make vertices out of the data points(make a new matrix out of 3 matrix(each row is a 3D point
                valid_indices = all(isfinite(vertices), 2); %remove unidentified values
                vertices = vertices(valid_indices, :); %keeping the rows of the matrix with valid values

                % generates a Delaunay triangulation using the X and Y coordinates from the vertices matrix
                faces = delaunay(vertices(:, 1), vertices(:, 2));

                % Create a solid volume from positive data
                [F_solid, V_solid] = surf2solid(faces, vertices, 'elevation', -6); %2 is theheight of solid bottom(can be changed based on the data)


                % Create Solid Cube

                %creating a solid cube which the x dimension is 130 and y simension is 180
                %the height of the cube is 5
                vertices = [0 0 0;
                    130 0 0;
                    130 180 0;
                    0 180 0;
                    0 0 5;
                    130 0 5;
                    130 180 5;
                    0 180 5];

                %2D triangulation using x and y points
                faces = delaunay(vertices(:, 1), vertices(:, 2));

                %converting the surface into solid using surf2solid command
                %put the elevation 10 and generating solid faces and solid vertices
                [Solid_faces, Solid_vertices] = surf2solid(faces, vertices, 'elevation', 10);


                % Add Custom Object on Top of Cube
                % Position custom object centered on top of cube (e.g., 75, 100, 20)
                custom_translation = [115, 15, 12];
                rotation_xy = [cosd(90) -sind(90) 0; sind(90) cosd(90) 0; 0 0 1];
                v_solid_rotated = (rotation_xy * V_solid')';
                %moving 3D object
                v_solid_translated = bsxfun(@plus, v_solid_rotated, custom_translation);
                f_solid_shifted = F_solid + size(Solid_vertices, 1)


                % Combine All and Export
                combined_V = [Solid_vertices;v_solid_translated];
                combined_F = [Solid_faces; f_solid_shifted];
                %
                TR = triangulation(combined_F, combined_F);


                % stlwrite(TR, 'cube_with_text_number_and_custom_object.stl');
                % disp('✅ Exported as cube_with_text_number_and_custom_object.stl');

                % Preview
                % figure;
                % patch('Faces', combined_F, 'Vertices', combined_V, ...
                %       'FaceColor', [0.8 0.3 1], 'EdgeColor', 'none');
                % axis equal;
                % camlight;
                % lighting gouraud;
                % title('Cube with 3D Text, Numbers, and Custom Object');
                % xlabel('X'); ylabel('Y'); zlabel('Z');
            end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Import
        function ImportButtonPushed(app, event)
        % Get the Raw data file
        [file,path] = uigetfile({'*.xlsx' ; '*.xls' ; '*.txt' ; '*.csv'} , 'Select a Data File');
        if file == 0
            return
        end
        
        app.ImportedFilestatusLabel.Text = ['Be Paitient, Uploading...'];
        drawnow; %to update any graphical things by matlab without delay
        %if no file is uploaded, return it
        
        filename = fullfile(path,file); % Get the file name and its path
        Data = readtable(filename); % read the choosen file

        %put the raw data's path and data into a property
        app.Raw_data = Data;
        app.filepath = path;

        % store the uploaded file
        app.selected_file= filename;

        %file text label
        app.ImportedFilestatusLabel.Text = ['Uploaded File: ', file];
        
        end

        % Value changed function: OutputStlnameEditField
        function OutputStlnameEditFieldValueChanged(app, event)
        % get the final file name as character from the field
        final_name = char(app.OutputStlnameEditField.Value);

        %put the STL file name in the property
        app.STL_name = final_name;
        end

        % Callback function: not associated with a component
        function SmoothingSliderValueChanging(app, event)

        end

        % Button pushed function: Create3DModelButton
        function Create3DModelButtonPushed(app, event)
        % Check if data has been loaded
        if isempty(app.selected_file)
            uialert(app.UIFigure, 'No data loaded! Please upload a file.', 'Error');
            return;
        end

        % Check if file name is provided
        if isempty(app.STL_name)
            uialert(app.UIFigure, 'Please enter a file name.', 'Error');
            return;
        end

        % If no smoothing factor is set, assign a default value
        if isempty(app.smoothing)
            app.smoothing = 6;
        end

        %check if the printed name is less than 13 characters
        if length(app.plot_name) > 13
            uialert(app.UIFigure, 'The printed name should be less than 14 characters', 'Error');
            return;
        end


        z = 1; %for starting the while loop

        while z==1

            %make a waitbar to show the processing
            progress = uiprogressdlg(app.UIFigure, ...
                'Title', 'Please Wait', ...
                'Message', 'Processing...', ...
                'Indeterminate', 'on');

            %recall for the saved stuff in property
            Data = app.Raw_data;
            smooth = app.smoothing ;
            startx = app.start_x;
            starty = app.start_y;
            endx = app.end_x;
            endy = app.end_y;
            data_name = app.plot_name;
            base_option = app.base;

            dimension_num = [startx endx starty endy];

            if length(dimension_num) < 4 & ~isempty(dimension_num)
                uialert(app.UIFigure, 'Please enter all four dimension.', 'Error');
                return;
            end

            %if there is no optional feature in hand
            % use the data to stl func
            if isempty(dimension_num) & isempty(data_name) & base_option==0
                [F_solid , V_solid , tri] = DataToStl( Data ,smooth );
                %if we have the name for printing and we didnt have
                %dimension numbers
                %use DataWithName Function
            elseif base_option == 1 & isempty(dimension_num) & isempty(data_name)
                [combined_V , combined_F ,TR] = DataWithBase(Data ,smooth)
                V_solid = combined_V;
                F_solid = combined_F;
                tri = TR;
            elseif isempty(dimension_num) & ~isempty(data_name)
                [combined_V , combined_F ,TR] = DataWithName( Data ,smooth , data_name )
                V_solid = combined_V;
                F_solid = combined_F;
                tri = TR;
                %if we have all optional feature
            elseif ~isempty(dimension_num) & ~isempty(data_name)
                [combined_V, combined_F , TR] = DataWithDetails( Data ,smooth , data_name , dimension_num)
                V_solid = combined_V;
                F_solid = combined_F;
                tri = TR;
            else
                %if we only have the dimension numbers
                [combined_V, combined_F , TR] = DataWithNumbers( Data ,smooth , dimension_num)
                V_solid = combined_V;
                F_solid = combined_F;
                tri = TR;
            end

            %if the entered file name has the format, then there is no
            %need to add format to it
            if endsWith(lower(app.STL_name), '.stl')
                ready_file = app.STL_name;
            else
                % if there is no format, then add it
                ready_file = [app.STL_name, '.stl'];
            end

            % if the user didnt put any directory, save the file in the
            % uploaded data's folder
            if isempty(app.finaldir)
                app.finaldir = app.filepath;
            end

            % make the full name of STL file
            finalfile = fullfile(app.finaldir , ready_file);

            %converting the data into STL file
            stlwrite(tri, finalfile);

            z = 2; %end the while loop
        end

        % Create a new UI figure and UIAxes
        newFig = uifigure('Name', '3D Solid Viewer', 'Position', [300, 300, 600, 500]);
        newAxes = uiaxes(newFig, 'Position', [60, 70, 480, 380]);

        % Plot the 3D patch
        patch('Faces', F_solid, ...
            'Vertices', V_solid, ...
            'FaceColor', 'magenta', ...
            'EdgeColor', 'none', ... %to look more smoothed
            'Parent', newAxes);

        % Set visualization properties
        axis(newAxes, 'vis3d');
        axis(newAxes, 'equal');
        grid(newAxes, 'on');

        % Set view and lighting
        view(newAxes, 3);
        camlight(newAxes, 'headlight');
        lighting(newAxes, 'gouraud');

        % Label the axes
        xlabel(newAxes, 'X-axis');
        ylabel(newAxes, 'Y-axis');
        zlabel(newAxes, 'Z-axis');

        % Title of plot
        title(newAxes, 'Solid Volume of the Final Version');

        % Enable interactive rotation
        rotate3d(newAxes, 'on');

        %Notify the user of success
        uialert(app.UIFigure, ['STL file saved as ', app.STL_name, '.stl'], 'Success');
        %

        end

        % Button pushed function: DirectoryButton
        function DirectoryButtonPushed(app, event)
        %get the directory
        selectedpath = uigetdir;

        %show the path in the label
        app.SelectSaveLocationLabel.Text = selectedpath;

        %store the path in property
        app.finaldir = selectedpath;
        end

        % Value changed function: PrintedNameEditField
        function PrintedNameEditFieldValueChanged(app, event)
        % get the title of the lot to print with it
        plot_name = char(app.PrintedNameEditField.Value);

        %put the STL file name in the property
        app.plot_name = plot_name;

        end

        % Value changed function: StartingXvalueEditField
        function StartingXvalueEditFieldValueChanged(app, event)
        starting_X = app.StartingXvalueEditField.Value;
        app.start_x = starting_X;
        end

        % Value changed function: EndingXvalueEditField
        function EndingXvalueEditFieldValueChanged(app, event)
        ending_X = app.EndingXvalueEditField.Value;
        app.end_x = ending_X;
        end

        % Value changed function: StartingYvalueEditField
        function StartingYvalueEditFieldValueChanged(app, event)
        starting_Y = app.StartingYvalueEditField.Value;
        app.start_y = starting_Y;
        end

        % Value changed function: EndingYvalueEditField
        function EndingYvalueEditFieldValueChanged(app, event)
        ending_Y = app.EndingYvalueEditField.Value;
        app.end_y = ending_Y;
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
        helpFig = uifigure('Name', 'Help', 'Position', [150 150 450 350]);
        uitextarea(helpFig, ...
            'Value', {
            ' Welcome to the 2D Data into Real World App', ...
            '', ...
            'First, upload your 2D matrix data file (.csv or .xlsx).', ...
            'Then, enter the output file name and press the Generate STL button.', ...
            '', ...
            'Optional Features:', ...
            '- Smoothing: You can adjust the smoothing level using the smoothing slider, depending on your data and noise level. The default value is 6. Be cautious of over-smoothing or under-smoothing the data.', ...
            '', ...
            '- Save Directory: You can choose a directory where your final STL file will be saved. If no directory is selected, the default is the same directory as the uploaded data file.', ...
            '',...
            '- Add Base: If you want to add an extra base under your model, use the checkbox labeled "Add Base" in the optional settings section.', ...
            '', ...
            '- Printed Name: To print a name on the wall of the base, enter your desired text in the "Printed Name" field.' ,...
            '',...
            '- Dimension Labels: If you want to print dimension numbers on the sides of the model, enter the desired values in the "Dimension Size" field.',...
            '   • starting and ending values: The start and end values for the x' ...
            '   and y axes only affect axis labels, not the plot’s size. ' ...
            '   Changing these values alters displayed numbers but not the  ' ...
            '   printed dimensions.', ...
            '',...
            'Use these options to customize your STL output. If you have any questions, feel free to contact support.' , ...
            '• hassanp2@ualberta.ca','• mahdiyehassanpoor@gmail.com'... 
            '',...
            } ,...
            'FontWeight', 'bold','FontName', 'Gadugi' , 'BackgroundColor','white', 'FontColor', 'Black',...
            'FontSize', 13 , ...
            'Editable', 'off', ...
            'Position', [10 10 430 320]);


        end

        % Value changed function: AddBaseCheckBox
        function AddBaseCheckBoxValueChanged(app, event)
        app.base = app.AddBaseCheckBox.Value;
        end

        % Value changed function: SmoothingSlider
        function SmoothingSliderValueChanged(app, event)
            %get the smoothing amount by the slider
            smoothing_factor =app.SmoothingSlider.Value;

            %store the slider's input in property
            app.smoothing = smoothing_factor;

            %show the amount of smoothing in the label
            app.smoothingFactorLabel.Text = [num2str(smoothing_factor)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Open instructions on how to use this app step-by-step.'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'NeededFilesforTesting', 'png_question.png');
            app.PushTool.Separator = 'on';
            app.DDataintoRealWorldLabel = uilabel(app.UIFigure);
            app.DDataintoRealWorldLabel.Text = '2D Data into Real World';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneratingSTLTab = uitab(app.TabGroup);
            app.GeneratingSTLTab.Title = 'Generating STL';
            app.OutputStlnameEditFieldLabel = uilabel(app.GeneratingSTLTab);
            app.OutputStlnameEditFieldLabel.Text = 'Output Stl name';
            app.OutputStlnameEditField = uieditfield(app.GeneratingSTLTab, 'text');
            app.OutputStlnameEditField.ValueChangedFcn = createCallbackFcn(app, @OutputStlnameEditFieldValueChanged, true);
            app.OutputStlnameEditField.Tooltip = {'Enter the desired name for the exported STL file (without extension)'};
            app.SmoothingSliderLabel = uilabel(app.GeneratingSTLTab);
            app.SmoothingSliderLabel.Text = 'Smoothing';
            app.SmoothingSlider = uislider(app.GeneratingSTLTab);
            app.SmoothingSlider.Limits = [4 8];
            app.SmoothingSlider.MajorTicks = [4 4.5 5 5.5 6 6.5 7 7.5 8];
            app.SmoothingSlider.ValueChangedFcn = createCallbackFcn(app, @SmoothingSliderValueChanged, true);
            app.SmoothingSlider.Tooltip = {'Adjust the level of smoothing applied to the surface before exporting. Higher values apply more smoothing'};
            app.SmoothingSlider.Value = 6;
            app.smoothingFactorLabel = uilabel(app.GeneratingSTLTab);
            app.smoothingFactorLabel.Tooltip = {'this is test'};
            app.smoothingFactorLabel.Text = 'smoothing Factor';
            app.Import = uibutton(app.GeneratingSTLTab, 'push');
            app.Import.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.Import.Icon = fullfile(pathToMLAPP, 'NeededFilesforTesting', 'Import.png');
            app.Import.Tooltip = {'Click to import a file for STL generation. Supported formats only'};
            app.Import.Text = 'Import';
            app.ImportedFilestatusLabel = uilabel(app.GeneratingSTLTab);
            app.ImportedFilestatusLabel.Text = 'Imported File status';
            app.DirectoryButton = uibutton(app.GeneratingSTLTab, 'push');
            app.DirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DirectoryButtonPushed, true);
            app.DirectoryButton.Icon = fullfile(pathToMLAPP, 'NeededFilesforTesting', 'Directory.png');
            app.DirectoryButton.Tooltip = {'Choose a folder where the generated STL file will be saved'};
            app.DirectoryButton.Text = 'Directory';
            app.SelectSaveLocationLabel = uilabel(app.GeneratingSTLTab);
            app.SelectSaveLocationLabel.Text = 'Select Save Location';
            app.Create3DModelButton = uibutton(app.GeneratingSTLTab, 'push');
            app.Create3DModelButton.ButtonPushedFcn = createCallbackFcn(app, @Create3DModelButtonPushed, true);
            app.Create3DModelButton.Tooltip = {'Click to generate and export the STL file using the specified name, smoothing factor, and output folder'};
            app.Create3DModelButton.Text = 'Create 3D Model';
            app.OptionalFeaturesTab = uitab(app.TabGroup);
            app.OptionalFeaturesTab.Tooltip = {''};
            app.OptionalFeaturesTab.Title = 'Optional Features';
            app.Image = uiimage(app.OptionalFeaturesTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_2302.png');
            app.DimensionLabels = uilabel(app.OptionalFeaturesTab);
            app.DimensionLabels.Tooltip = {'Include numerical dimension labels'};
            app.DimensionLabels.Text = 'Dimension Labels';
            app.StartingXvalueEditFieldLabel = uilabel(app.OptionalFeaturesTab);
            app.StartingXvalueEditFieldLabel.Text = 'Starting X value';
            app.StartingXvalueEditField = uieditfield(app.OptionalFeaturesTab, 'numeric');
            app.StartingXvalueEditField.ValueChangedFcn = createCallbackFcn(app, @StartingXvalueEditFieldValueChanged, true);
            app.StartingXvalueEditField.Tooltip = {'Starting of X axis or first dimension''s starting value'};
            app.EndingXvalueEditFieldLabel = uilabel(app.OptionalFeaturesTab);
            app.EndingXvalueEditFieldLabel.Text = 'Ending X value';
            app.EndingXvalueEditField = uieditfield(app.OptionalFeaturesTab, 'numeric');
            app.EndingXvalueEditField.ValueChangedFcn = createCallbackFcn(app, @EndingXvalueEditFieldValueChanged, true);
            app.EndingXvalueEditField.Tooltip = {'Ending of X axis or '; 'first dimension''s ending value'};
            app.StartingYvalueEditFieldLabel = uilabel(app.OptionalFeaturesTab);
            app.StartingYvalueEditFieldLabel.Text = 'Starting Y value';
            app.StartingYvalueEditField = uieditfield(app.OptionalFeaturesTab, 'numeric');
            app.StartingYvalueEditField.ValueChangedFcn = createCallbackFcn(app, @StartingYvalueEditFieldValueChanged, true);
            app.StartingYvalueEditField.Tooltip = {'Starting of Y axis or the second dimension''s starting value'};
            app.EndingYvalueEditFieldLabel = uilabel(app.OptionalFeaturesTab);
            app.EndingYvalueEditFieldLabel.Text = 'Ending Y value';
            app.EndingYvalueEditField = uieditfield(app.OptionalFeaturesTab, 'numeric');
            app.EndingYvalueEditField.ValueChangedFcn = createCallbackFcn(app, @EndingYvalueEditFieldValueChanged, true);
            app.EndingYvalueEditField.Tooltip = {'Ending of Y axis or the second dimension''s ending value'};
            app.PrintedNameEditFieldLabel = uilabel(app.OptionalFeaturesTab);
            app.PrintedNameEditFieldLabel.Text = 'Printed Name';
            app.PrintedNameEditField = uieditfield(app.OptionalFeaturesTab, 'text');
            app.PrintedNameEditField.ValueChangedFcn = createCallbackFcn(app, @PrintedNameEditFieldValueChanged, true);
            app.PrintedNameEditField.Tooltip = {'The Name to be 3D printed with the data'};
            app.AddBaseCheckBox = uicheckbox(app.OptionalFeaturesTab);
            app.AddBaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddBaseCheckBoxValueChanged, true);
            app.AddBaseCheckBox.Tooltip = {'Add a solid base under the model.'};
            app.AddBaseCheckBox.Text = 'Add Base';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STLApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end